classdef shaftdesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DiametermEditFieldLabel         matlab.ui.control.Label
        DiametermEditField              matlab.ui.control.NumericEditField
        CriticalSpeedRPMEditFieldLabel  matlab.ui.control.Label
        CriticalSpeedRPMEditField       matlab.ui.control.NumericEditField
        NaturalFrequencyEditFieldLabel  matlab.ui.control.Label
        NaturalFrequencyEditField       matlab.ui.control.NumericEditField
        StaticDeflectionmmEditFieldLabel  matlab.ui.control.Label
        StaticDeflectionmmEditField     matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        MaterialPropertiesPanel         matlab.ui.container.Panel
        EnduranceLimitMpaEditFieldLabel  matlab.ui.control.Label
        EnduranceLimitMpaEditField      matlab.ui.control.NumericEditField
        YoungsModulusGpaEditFieldLabel  matlab.ui.control.Label
        YoungsModulusGpaEditField       matlab.ui.control.NumericEditField
        DensityKgm3EditFieldLabel       matlab.ui.control.Label
        DensityKgm3EditField            matlab.ui.control.NumericEditField
        YieldStrengthMpaEditFieldLabel  matlab.ui.control.Label
        YieldStrengthMpaEditField       matlab.ui.control.NumericEditField
        LoadingConditionsPanel          matlab.ui.container.Panel
        AppliedTorqueNmEditFieldLabel   matlab.ui.control.Label
        AppliedTorqueNmEditField        matlab.ui.control.NumericEditField
        MassKgEditFieldLabel            matlab.ui.control.Label
        MassKgEditField                 matlab.ui.control.NumericEditField
        LengthmEditFieldLabel           matlab.ui.control.Label
        LengthmEditField                matlab.ui.control.NumericEditField
        ReserveFactorEditFieldLabel     matlab.ui.control.Label
        ReserveFactorEditField          matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReserveFactorEditField
        function ReserveFactorEditFieldValueChanged(app, event)
            Rf = app.ReserveFactorEditField.Value;
            
        end

        % Value changed function: AppliedTorqueNmEditField
        function AppliedTorqueNmEditFieldValueChanged(app, event)
            T = app.AppliedTorqueNmEditField.Value;
            
        end

        % Value changed function: MassKgEditField
        function MassKgEditFieldValueChanged(app, event)
            Mass = app.MassKgEditField.Value;
            
        end

        % Value changed function: YieldStrengthMpaEditField
        function YieldStrengthMpaEditFieldValueChanged(app, event)
            Sig_y = app.YieldStrengthMpaEditField.Value;
            
        end

        % Value changed function: EnduranceLimitMpaEditField
        function EnduranceLimitMpaEditFieldValueChanged(app, event)
            Sig_e = app.EnduranceLimitMpaEditField.Value;
            
        end

        % Value changed function: LengthmEditField
        function LengthmEditFieldValueChanged(app, event)
            L = app.LengthmEditField.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           % Input values
            Rf = app.ReserveFactorEditField.Value;
            Mass = app.MassKgEditField.Value;
            Sig_e = app.EnduranceLimitMpaEditField.Value*(10^6);
            Sig_y = app.YieldStrengthMpaEditField.Value*(10^6);
            T = app.AppliedTorqueNmEditField.Value;
            L = app.LengthmEditField.Value;
            E = app.YoungsModulusGpaEditField.Value*(10^9);
            row = app.DensityKgm3EditField.Value;
           % Calculate moment 
            Mo = 1/4 * Mass*9.81*L;
           % Calculate Diameter
            D = (((32*Rf)/pi)*sqrt((Mo/Sig_e)^2+(3/4)*(T/Sig_y)^2))^(1/3);
           % Calculate second moment of area
            I = pi/64 * (D^4);
           % Calculate static deflection
            Delta = (Mass*9.81*(L^3))/(48*E*I)*10^3;
           % Calculate critical speed in RPM
            Omega_c = sqrt(9.81/Delta)*(60/(2*pi));
           % Calculate Area
           A = pi/4 * (D^2);
           % Calculate Natural Frequency in Hz
            Omega_r = ((pi/L)^2)*(sqrt((E*I)/(row*A)))*1/(2*pi);
           % Output values
            app.DiametermEditField.Value = D;
            app.StaticDeflectionmmEditField.Value = Delta;
            app.CriticalSpeedRPMEditField.Value = Omega_c;
            app.NaturalFrequencyEditField.Value = Omega_r;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiametermEditFieldLabel = uilabel(app.UIFigure);
            app.DiametermEditFieldLabel.Text = 'Diameter(m)';
            app.DiametermEditField = uieditfield(app.UIFigure, 'numeric');
            app.CriticalSpeedRPMEditFieldLabel = uilabel(app.UIFigure);
            app.CriticalSpeedRPMEditFieldLabel.Text = 'Critical Speed (RPM)';
            app.CriticalSpeedRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.NaturalFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.NaturalFrequencyEditFieldLabel.Text = 'Natural Frequency';
            app.NaturalFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.StaticDeflectionmmEditFieldLabel = uilabel(app.UIFigure);
            app.StaticDeflectionmmEditFieldLabel.Text = 'Static Deflection(mm)';
            app.StaticDeflectionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaterialPropertiesPanel = uipanel(app.UIFigure);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.EnduranceLimitMpaEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.EnduranceLimitMpaEditFieldLabel.Text = 'Endurance Limit (Mpa)';
            app.EnduranceLimitMpaEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.EnduranceLimitMpaEditField.Limits = [0 Inf];
            app.EnduranceLimitMpaEditField.ValueChangedFcn = createCallbackFcn(app, @EnduranceLimitMpaEditFieldValueChanged, true);
            app.EnduranceLimitMpaEditField.Value = 100;
            app.YoungsModulusGpaEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.YoungsModulusGpaEditFieldLabel.Text = 'Youngs Modulus (Gpa)';
            app.YoungsModulusGpaEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.YoungsModulusGpaEditField.Limits = [0 Inf];
            app.YoungsModulusGpaEditField.Value = 200;
            app.DensityKgm3EditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.DensityKgm3EditFieldLabel.Text = 'Density (Kg/m^3)';
            app.DensityKgm3EditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.DensityKgm3EditField.Limits = [0 Inf];
            app.DensityKgm3EditField.Value = 7000;
            app.YieldStrengthMpaEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.YieldStrengthMpaEditFieldLabel.Text = 'Yield Strength (Mpa)';
            app.YieldStrengthMpaEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.YieldStrengthMpaEditField.Limits = [0 Inf];
            app.YieldStrengthMpaEditField.ValueChangedFcn = createCallbackFcn(app, @YieldStrengthMpaEditFieldValueChanged, true);
            app.YieldStrengthMpaEditField.Value = 700;
            app.LoadingConditionsPanel = uipanel(app.UIFigure);
            app.LoadingConditionsPanel.Title = 'Loading Conditions';
            app.AppliedTorqueNmEditFieldLabel = uilabel(app.LoadingConditionsPanel);
            app.AppliedTorqueNmEditFieldLabel.Text = 'Applied Torque (Nm)';
            app.AppliedTorqueNmEditField = uieditfield(app.LoadingConditionsPanel, 'numeric');
            app.AppliedTorqueNmEditField.Limits = [0 Inf];
            app.AppliedTorqueNmEditField.ValueChangedFcn = createCallbackFcn(app, @AppliedTorqueNmEditFieldValueChanged, true);
            app.AppliedTorqueNmEditField.Value = 200;
            app.MassKgEditFieldLabel = uilabel(app.LoadingConditionsPanel);
            app.MassKgEditFieldLabel.Text = 'Mass (Kg)';
            app.MassKgEditField = uieditfield(app.LoadingConditionsPanel, 'numeric');
            app.MassKgEditField.Limits = [0 Inf];
            app.MassKgEditField.ValueChangedFcn = createCallbackFcn(app, @MassKgEditFieldValueChanged, true);
            app.MassKgEditField.Value = 10;
            app.LengthmEditFieldLabel = uilabel(app.LoadingConditionsPanel);
            app.LengthmEditFieldLabel.Text = 'Length (m)';
            app.LengthmEditField = uieditfield(app.LoadingConditionsPanel, 'numeric');
            app.LengthmEditField.Limits = [0 Inf];
            app.LengthmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthmEditFieldValueChanged, true);
            app.LengthmEditField.Value = 1.5;
            app.ReserveFactorEditFieldLabel = uilabel(app.LoadingConditionsPanel);
            app.ReserveFactorEditFieldLabel.Text = 'Reserve Factor';
            app.ReserveFactorEditField = uieditfield(app.LoadingConditionsPanel, 'numeric');
            app.ReserveFactorEditField.Limits = [0 Inf];
            app.ReserveFactorEditField.ValueChangedFcn = createCallbackFcn(app, @ReserveFactorEditFieldValueChanged, true);
            app.ReserveFactorEditField.Value = 2;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shaftdesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end